classdef ONMD_soft_App_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CLEARALLVARIABLESButton         matlab.ui.control.Button
        CloseallwindowsButton           matlab.ui.control.Button
        ONMD_soft_App_2Label            matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        FileTab                         matlab.ui.container.Tab
        DisplayLastFrameButton          matlab.ui.control.Button
        DisplayFirstFrameButton         matlab.ui.control.Button
        DeltaFramesEditField            matlab.ui.control.EditField
        DeltaFramesEditFieldLabel       matlab.ui.control.Label
        LastFrameEditField              matlab.ui.control.EditField
        LastFrameEditFieldLabel         matlab.ui.control.Label
        FirstFrameEditField             matlab.ui.control.EditField
        FirstFrameEditFieldLabel        matlab.ui.control.Label
        NameMovieEditField              matlab.ui.control.EditField
        NameMovieEditFieldLabel         matlab.ui.control.Label
        LoadMovieButton                 matlab.ui.control.Button
        PathMovieEditField              matlab.ui.control.EditField
        PathMovieEditFieldLabel         matlab.ui.control.Label
        MovieQualityTestTab             matlab.ui.container.Tab
        ChangingPixelsImageContrastEditField  matlab.ui.control.NumericEditField
        ChangingPixelsImageContrastEditFieldLabel  matlab.ui.control.Label
        DisplayPixelsIntensityVariationsButton  matlab.ui.control.Button
        DisplayChangingPixelsButton     matlab.ui.control.Button
        CellRecognitionTab              matlab.ui.container.Tab
        AutomaticROIboxsizeEditField    matlab.ui.control.NumericEditField
        AutomaticROIboxsizeEditFieldLabel  matlab.ui.control.Label
        AutomaticSelectionPanel         matlab.ui.container.Panel
        ValidateAutomaticSelectionButton  matlab.ui.control.Button
        RightClicktostopLabel           matlab.ui.control.Label
        RemoveROIsButton                matlab.ui.control.Button
        ManualSelectionPanel            matlab.ui.container.Panel
        ValidateManualSelectionButton   matlab.ui.control.Button
        nROIManSelectEditField          matlab.ui.control.NumericEditField
        nROIManSelectEditFieldLabel     matlab.ui.control.Label
        CloseDiskSizeEditField          matlab.ui.control.EditField
        CloseDiskSizeEditFieldLabel     matlab.ui.control.Label
        DilatDiskSizeEditField          matlab.ui.control.EditField
        DilatDiskSizeEditFieldLabel     matlab.ui.control.Label
        MorphologicalFilteringButton    matlab.ui.control.Button
        BasicImageProcessingTab         matlab.ui.container.Tab
        DivideallFrameswithFFImageCheckBox  matlab.ui.control.CheckBox
        FlatFieldMovieNameEditField     matlab.ui.control.EditField
        FlatFieldMovieNameEditFieldLabel  matlab.ui.control.Label
        FlatFieldMoviePathEditField     matlab.ui.control.EditField
        FlatFieldMoviePathEditFieldLabel  matlab.ui.control.Label
        LoadFlatFieldMovieButton        matlab.ui.control.Button
        CorrectNonUniformIlluminationButton  matlab.ui.control.Button
        DisplayResultsTab               matlab.ui.container.Tab
        DisplayDisplacementfCellRadiusCheckBox  matlab.ui.control.CheckBox
        DisplayAllROIsCheckBox          matlab.ui.control.CheckBox
        nViolinsEditField               matlab.ui.control.NumericEditField
        nViolinsEditFieldLabel          matlab.ui.control.Label
        DisplayViolinPlotforEveryCellCheckBox  matlab.ui.control.CheckBox
        YScaleViolinPlotEditField       matlab.ui.control.NumericEditField
        YScaleViolinPlotEditFieldLabel  matlab.ui.control.Label
        DisplayBrighthnessofEveryCellCheckBox  matlab.ui.control.CheckBox
        DisplayTotalDisplacmentofEveryCellCheckBox  matlab.ui.control.CheckBox
        XYScaleofDisplacmentPlotEditField  matlab.ui.control.NumericEditField
        XYScaleofDisplacmentPlotEditFieldLabel  matlab.ui.control.Label
        DisplayXYDisplacmentofEveryCellCheckBox  matlab.ui.control.CheckBox
        DisplayResultsButton            matlab.ui.control.Button
        ExporttoExcelTab                matlab.ui.container.Tab
        ExportTotalDisplacementofAllROIsButton  matlab.ui.control.Button
        ExportBrightnessDataButton      matlab.ui.control.Button
        ExportXYDisplacementDataButton  matlab.ui.control.Button
    end


    properties (Access = public)
        PathMovie % path of the movie
        NameMovie %name of the movie
        PathNameMovie % path and name of the movie
        FirstFrame %first frame of the movie
        LastFrame %last frame of the movie
        DeltaFrames %distance between two concsecutive frames to be considered
        Movie % Movie
        ImMaxDifMovie
        ChangeMov_a_b
        Nfig % number of figure 
        ImgSurfCellDef %image of the cells surfaces
        
        FlatFieldMovie % Flat field movie
        ImageFF %Flat field image
        
        NroiManualSelect% number of ROI manually selected
        
        DispX %ROI displacment in x
        DispY %ROI displacment in Y
        DispT %ROI total displacment
        DifBright %ROI modification in brightness
       
        posL_x %x coordinates of the ROI left corner
        posR_x
        posU_y
        posL_y  
        
        TotDisplPfr % total displacment pro frame

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadMovieButton
        function LoadMovieButtonPushed(app, event)
            
            app.PathMovie=app.PathMovieEditField.Value;
            app.NameMovie=app.NameMovieEditField.Value;
            
            %Add the the name to the path
            app.PathNameMovie=strcat(app.PathMovie,app.NameMovie);% path and name of the movie in one variable
            disp(app.PathNameMovie);
            
            app.FirstFrame=str2double(app.FirstFrameEditField.Value);
            disp(app.FirstFrame);
            
            app.LastFrame=str2double(app.LastFrameEditField.Value);
            disp(app.LastFrame);
            
            app.DeltaFrames=str2double(app.DeltaFramesEditField.Value);
            disp(app.DeltaFrames);
            
            app.Nfig=0; %Initialisation of the figures counter
            app.Movie = VideoReader(app.PathNameMovie); % Load the movie
        end

        % Button pushed function: DisplayChangingPixelsButton
        function DisplayChangingPixelsButtonPushed(app, event)
                %Displays the pixels that change the most
                %Displays a graph that shows the difference between two consecutive frames
                %en prenant la premiere image du film et en la soustrayant de la seconde et
                %en cummulant les differences
            
                %im_diff: image qui montre les zones qui changent le plus
                %cette image a subi une egalisation d'histogramme
            
                %video: video ? analyser
                %first_frame: premiere image
                %n_frames: nombre d images a analyser
            
                % %01_11_2019 ok
            
            
                %lecture premi?re image
                app.Movie; %
                
                im_col=read(app.Movie, [app.FirstFrame]);%first frame 
                image_a=rgb2gray(im_col); %met l'image en nb
                im_diff=immultiply(image_a,0); %creation d une image zero


                h=waitbar(0,'Calculating pixels that change the most');
                %for n=app.FirstFrame:app.LastFrame
                
                n=app.DeltaFrames;
                while n<app.LastFrame-app.DeltaFrames
                       im_col=read(app.Movie, [n]);%frame nb 1
                       image_b=rgb2gray(im_col);
    
                       %soustraie deux images
                       im_absdiff=imabsdiff(image_a, image_b); 
                       change_a_b(n)=sum(sum(im_absdiff)); %stores the number of pixels that have changed between image a and b
                       im_diff=imadd(im_absdiff,im_diff);
                       %permet de ne pas saturer si il y a bcp d'images
                       im_diff=immultiply(im_diff,app.ChangingPixelsImageContrastEditField.Value); %(0.6)
                       image_a=image_b;
                       waitbar(n / (app.LastFrame-app.FirstFrame));
                       n=n+app.DeltaFrames;
                end;
                close(h);


                im_diff = imadjust(im_diff,stretchlim(im_diff),[]);
                
                app.Nfig=app.Nfig+1; figure(app.Nfig);
                imshow(im_diff,jet);
                
                app.Nfig=app.Nfig+1; figure(app.Nfig);
                plot(change_a_b)% Displays the number of pixels that have changed between image a and b
                title(app.NameMovie); 
                xlabel('Frames'); 
                ylabel('Difference between two consecutive frames');
                grid on
                
        end

        % Button pushed function: 
        % DisplayPixelsIntensityVariationsButton
        function DisplayPixelsIntensityVariationsButtonPushed(app, event)
            for n=app.FirstFrame:app.LastFrame
                Image=read(app.Movie, [n]);%frame nb 1
                Image=rgb2gray(Image); %met l'image en nb
                sumPixImg(n)=sum(sum(Image));
        
                waitbar(n/(app.LastFrame-app.FirstFrame));
            end
            app.Nfig=app.Nfig+1; figure(app.Nfig);
               plot(sumPixImg(2:end));
            min(sumPixImg)
            max(sumPixImg)
            title([app.NameMovie, '  ','total brightness of Frames ',num2str(app.FirstFrame), ' - ',num2str(app.LastFrame)]);
            grid on  
        end

        % Button pushed function: DisplayFirstFrameButton
        function DisplayFirstFrameButtonPushed(app, event)
                Image=read(app.Movie, [app.FirstFrame]);%frame nb 1
                Image=rgb2gray(Image); %met l'image en nb
                ImageOrig=Image;
                app.Nfig=app.Nfig+1; figure(app.Nfig);
                imshow(Image)
                title(app.NameMovie)
        end

        % Button pushed function: DisplayLastFrameButton
        function DisplayLastFrameButtonPushed(app, event)
                Image=read(app.Movie, [app.LastFrame]);%frame nb 1
                Image=rgb2gray(Image); %met l'image en nb
                ImageOrig=Image;
                app.Nfig=app.Nfig+1; figure(app.Nfig);
                imshow(Image)
                title(app.NameMovie)
        end

        % Button pushed function: MorphologicalFilteringButton
        function MorphologicalFilteringButtonPushed(app, event)
                Image=read(app.Movie, [app.FirstFrame]);%frame nb 1
                Image=rgb2gray(Image); %met l'image en nb
                
                DilatDiskSize=str2double(app.DilatDiskSizeEditField.Value);
                se = strel('disk',DilatDiskSize);%10
                Image=imdilate(Image,se);
              
                %lecture de la fenetre d'edition du dilat disk size
                CloseDiskSize=str2double(app.CloseDiskSizeEditField.Value);
                se = strel('disk',CloseDiskSize);%2
                Image=imclose(Image,se);
            
                
                 %Seuillage automatique
                level = graythresh(Image);
                ImgSurfCell= im2bw(Image,level);
             
                
                % Clear borders
                 ImgSurfCell = imclearborder(ImgSurfCell);
                 
                
                 %FudgeFactor=str2double(get(handles.e_FudgeFactor,'String'));% 0.8
                [~, threshold] = edge(ImgSurfCell, 'sobel');
                fudgeFactor = 1;%0.5
                BWs = edge(ImgSurfCell,'sobel', threshold * fudgeFactor);
                
                Image=read(app.Movie, [app.FirstFrame]);%frame nb 1
                ImageOrig=rgb2gray(Image); %met l'image en nb
                
                Image=imfuse(BWs,ImageOrig);
                app.Nfig=app.Nfig+1; figure(app.Nfig);
                imshow(Image);
                title([app.NameMovie, '  ','Dilat D. size ',num2str(DilatDiskSize), ' Close D. size ',num2str(CloseDiskSize)]);
                              
                s = regionprops(ImgSurfCell,'centroid');
                centroids = cat(1, s.Centroid);
                
                hold on
                for n=1:length(centroids)
                    %Writes ROI nb 
                    numero=int2str(n);
                    text(centroids(n,1),centroids(n,2),numero,'Color','white');
                    
                    %Draws a box around the centroid of each ROI
                    ROIboxSize=app.AutomaticROIboxsizeEditField.Value;
                    posC_x(n)=centroids(n,1);
                    posC_y(n)=centroids(n,2);
                    
                    app.posL_x(n)=posC_x(n)-ROIboxSize;
                    app.posR_x(n)=posC_x(n)+ROIboxSize;
                    app.posU_y(n)=posC_y(n)-ROIboxSize;
                    app.posL_y(n)=posC_y(n)+ROIboxSize;
                    
                    plot([app.posL_x(n),app.posL_x(n)],[app.posU_y(n),app.posL_y(n)],'y','LineWidth',1);%vertG
                    plot([app.posR_x(n),app.posR_x(n)],[app.posU_y(n),app.posL_y(n)],'y','LineWidth',1);%vertD
                    plot([app.posL_x(n),app.posR_x(n)],[app.posU_y(n),app.posU_y(n)],'y','LineWidth',1);%horiU
                    plot([app.posL_x(n),app.posR_x(n)],[app.posL_y(n),app.posL_y(n)],'y','LineWidth',1);%horiU
                    
                end
                hold off
                     
                %stats = regionprops('table',ImgSurfCell,'Centroid','MajorAxisLength','MinorAxisLength','EquivDiameter')
                    
                app.ImgSurfCellDef=ImgSurfCell;
                               
        end

        % Button pushed function: RemoveROIsButton
        function RemoveROIsButtonPushed(app, event)
            
                   
            button=1;
            while button<3
                   figure(5)
                   imshow(app.ImgSurfCellDef);
                   
                   s = regionprops(app.ImgSurfCellDef,'centroid')
                   %s = regionprops('table',app.ImgSurfCellDef,'Centroid','MajorAxisLength','MinorAxisLength','EquivDiameter')
                   centroids = cat(1, s.Centroid)
                   display('length centroids')
                   length(centroids)
                
                    hold on
                    for n=1:length(centroids)
                        numero=int2str(n);
                        text(centroids(n,1),centroids(n,2),numero,'Color','red');
                    end
               
                    %Pour enregistrer les coordon?es x et y de la souris lorsque on clique 
                    [x,y, button] = ginput(1);
                    pos_x=x;
                    pos_y=y;
                
                    plot(pos_x,pos_y,'+');
                    hold off
                    
                    for n=1:length(centroids)
                        dist(n)=sqrt((pos_x-centroids(n,1))^2+(pos_y-centroids(n,2))^2);
                    end
                    indice= find(dist == min(dist)) %le no du ROI le plus proce
                    
           
                   pos_x=int16(x);
                   pos_y=int16(y);
                     
                   app.ImgSurfCellDef=mat2gray(app.ImgSurfCellDef);
                   BW = grayconnected(app.ImgSurfCellDef,pos_y,pos_x);
                            
                   app.ImgSurfCellDef=im2bw(app.ImgSurfCellDef);
                   temp_A=imsubtract(app.ImgSurfCellDef, BW);
                   app.ImgSurfCellDef=temp_A;
                            
                   app.ImgSurfCellDef=imbinarize(app.ImgSurfCellDef)
                             
                   figure(5)
                   imshow(app.ImgSurfCellDef);
            end;
        end

        % Button pushed function: CloseallwindowsButton
        function CloseallwindowsButtonPushed(app, event)
            close all
        end

        % Button pushed function: 
        % CorrectNonUniformIlluminationButton
        function CorrectNonUniformIlluminationButtonPushed(app, event)
                
            if app.DivideallFrameswithFFImageCheckBox.Value==1
            
                Image=read(app.Movie, [app.FirstFrame]);%frame nb 1
                Image=rgb2gray(Image); %met l'image en nb
                
            %                 sigma=30;
            %                 Image = imflatfield(Image,sigma)
                
                ImgFF=imdivide(app.ImageFF,100);
                ImageCorr=imdivide(Image,ImgFF);
              %  Image=immultiply(Image,20);
                %max(Image)
                
            %                 fact=max(Image);
            %                 facteur=256/fact;
            %                 Image=immultiply(Image,facteur);


                app.Nfig=app.Nfig+1; figure(app.Nfig);
                imshow(ImageCorr);
                title('Flat Corrected Image')
                
            %                 ImDiff=imsubtract(ImageCorr,Image)
            %                 ImDiff=imadjust(ImDiff);
            %                 app.Nfig=app.Nfig+1; figure(app.Nfig);
            %                 imshow(ImDiff)
            %                 title('Difference')
                
                
            end;


            %                 background = imopen(Image,strel('disk',10)); %15
            %                 % Display the Background Approximation as a Surface
            %                 figure
            %                 surf(double(background(1:8:end,1:8:end))),zlim([0 255]);
            %                 ax = gca;
            %                 ax.YDir = 'reverse';
            %                 
            %                 ImageSansBackground = Image - background;
            %                 imshow(ImageSansBackground)
                
            %                 ImageSansBackground = imadjust(ImageSansBackground);
            %                 imshow(ImageSansBackground);
                
        end

        % Button pushed function: LoadFlatFieldMovieButton
        function LoadFlatFieldMovieButtonPushed(app, event)
                
                  PathFFmovie=app.FlatFieldMoviePathEditField.Value;
                  NameFFmovie=app.FlatFieldMovieNameEditField.Value;
            
                 %Add the the name to the path
                 PathNameFFMovie=strcat(PathFFmovie,NameFFmovie);% path and name of the movie in one variable
                 disp(PathNameFFMovie);
                  
                 app.FlatFieldMovie = VideoReader(PathNameFFMovie) % Load the movie
                 ImgFF=read(app.FlatFieldMovie, [1]);%frame nb 1
                 app.ImageFF=rgb2gray(ImgFF); %met l'image en nb
            %                 
                 %app.Nfig=app.Nfig+1; figure(app.Nfig);
                 figure
                 imshow(app.ImageFF)
                 title(PathNameFFMovie)
        end

        % Button pushed function: ValidateAutomaticSelectionButton
        function ValidateAutomaticSelectionButtonPushed(app, event)
            %Display BW image of ImgSurfCellDef 
                 app.Nfig=app.Nfig+1; figure(app.Nfig);                 
                 imshow(app.ImgSurfCellDef);
                 title(app.NameMovie)
                 
                 s = regionprops(app.ImgSurfCellDef,'centroid')
                 %s = regionprops('table',app.ImgSurfCellDef,'Centroid','MajorAxisLength','MinorAxisLength','EquivDiameter')
                 centroids = cat(1, s.Centroid)
                 display('length centroids')
                 length(centroids)
            
                
                hold on
                for n=1:length(centroids)
                    %Writes ROI nb 
                    numero=int2str(n);
                    text(centroids(n,1),centroids(n,2),numero,'Color','red');
                    
                    %Draws a box around the centroid of each ROI
                    ROIboxSize=app.AutomaticROIboxsizeEditField.Value;
                    posC_x(n)=centroids(n,1);
                    posC_y(n)=centroids(n,2);
                    
                    app.posL_x(n)=posC_x(n)-ROIboxSize;
                    app.posR_x(n)=posC_x(n)+ROIboxSize;
                    app.posU_y(n)=posC_y(n)-ROIboxSize;
                    app.posL_y(n)=posC_y(n)+ROIboxSize;
                    
                    plot([app.posL_x(n),app.posL_x(n)],[app.posU_y(n),app.posL_y(n)],'y','LineWidth',1);%vertG
                    plot([app.posR_x(n),app.posR_x(n)],[app.posU_y(n),app.posL_y(n)],'y','LineWidth',1);%vertD
                    plot([app.posL_x(n),app.posR_x(n)],[app.posU_y(n),app.posU_y(n)],'y','LineWidth',1);%horiU
                    plot([app.posL_x(n),app.posR_x(n)],[app.posL_y(n),app.posL_y(n)],'y','LineWidth',1);%horiU
                    
                end
                hold off
                
                
                 FirstFrameOnly=0;
                 app.NroiManualSelect=length(centroids);
            
                %-----Call the function that calculates the displacment in X,Y, total,
                %and changes in brightness of the roi
                
                [app.DispX,app.DispY,app.DispT,app.DifBright]=f_TrackRoiDispl(app.posL_x,app.posU_y,app.posR_x,app.posL_y,app.Movie,app.FirstFrame,app.LastFrame,app.DeltaFrames,app.NroiManualSelect,FirstFrameOnly);       
     
        end

        % Button pushed function: ValidateManualSelectionButton
        function ValidateManualSelectionButtonPushed(app, event)
              %numbre of ROIs to be selected manually
            app.NroiManualSelect=app.nROIManSelectEditField.Value;
            
            %Load first image
            Image=read(app.Movie, [app.FirstFrame]);%frame nb 1
            Image=rgb2gray(Image); %met l'image en nb
            
            %dispalys first image
            app.Nfig=app.Nfig+1; figure(app.Nfig);
            imshow(Image)
            title(app.NameMovie)
            hold on
            
            
            %interactively selects the ROIs, draws a red square around, prints its 
            %number on the image and records the coordinates of all manually selected ROIs
            for n=1:app.NroiManualSelect
                [ x1,y1,x2,y2 ] = f_SelectROI(n)%selects the ROI
                             
                app.posL_x(n)=x1;
                app.posU_y(n)=y1;
                app.posR_x(n)=x2;
                app.posL_y(n)=y2; 
             end;
            hold off
            
            FirstFrameOnly=0;
             
            %-----Call the function that calculates the displacment in X,Y, total,
            %and changes in brightness of the roi
        
            [app.DispX,app.DispY,app.DispT,app.DifBright]=f_TrackRoiDispl(app.posL_x,app.posU_y,app.posR_x,app.posL_y,app.Movie,app.FirstFrame,app.LastFrame,app.DeltaFrames,app.NroiManualSelect,FirstFrameOnly);
            
        end

        % Button pushed function: DisplayResultsButton
        function DisplayResultsButtonPushed(app, event)
               %-----------Display XY displacment and CM of all the roi on different figures------------------
               %initialisation for center of mass calculation
               tMax=round((app.LastFrame-app.FirstFrame)/app.DeltaFrames)+1;
               Xsum(1:tMax,1:app.NroiManualSelect)=0;
               Ysum(1:tMax,1:app.NroiManualSelect)=0;
     
                if app.DisplayXYDisplacmentofEveryCellCheckBox.Value==1 
               
                for Roi=1:app.NroiManualSelect
                    app.Nfig=app.Nfig+1; figure(app.Nfig);
                    hold on
                    Xo=app.DispX(1,Roi);
                    Yo=app.DispY(1,Roi);
            
                    t=1; %time counter for center of mass calculation
                    for Img=app.FirstFrame:(app.LastFrame-app.DeltaFrames)          
                        X=app.DispX(Img+app.DeltaFrames,Roi);
                        Y=app.DispY(Img+app.DeltaFrames,Roi);
                        %Tracage d'une ligne entre deux points 
                        plot([Xo,X],[Yo,Y],'r','LineWidth',2);
                        Xo=X;
                        Yo=Y;
                
                        %center of mass calculation
                        Xsum(t,Roi)=X;  %All x displacements for this Roi 
                        Ysum(t,Roi)=Y;
                        t=t+1;
                    end;
                 
                    title([app.NameMovie, '  ','path cell ',num2str(Roi),' Frames ',num2str(app.FirstFrame), ' - ',num2str(app.LastFrame)]);
                    grid on
                    xlabel('X displacement [pixels]'); ylabel('Y displacement [pixels]')
        
                    %reads the scale of the XY plot                   
                    XYscDispPlot=app.XYScaleofDisplacmentPlotEditField.Value;
                    axis([-XYscDispPlot XYscDispPlot -XYscDispPlot XYscDispPlot]); %                           <----------------------------
                    hold off
                end; 
        
                %center of mass calculation
                %display of all ROI displacment on a single figure
                tMax=t-1
                app.Nfig=app.Nfig+1; figure(app.Nfig);
                hold on
                for Roi=1:app.NroiManualSelect
                    plot(Xsum(1:tMax,Roi),Ysum(1:tMax,Roi))
                end;
                grid on
                title([app.NameMovie, '  ','path cells + disp centre of mass of all cells',' Frames ',num2str(app.FirstFrame), ' - ',num2str(app.LastFrame)]);
        
                %calculates and display the center of mass off all the cells as a f(t) 
                for t=1:tMax
                    CMassX(t)=mean(Xsum(t,1:app.NroiManualSelect));
                    CMassY(t)=mean(Ysum(t,1:app.NroiManualSelect));
                end;
                 plot(CMassX, CMassY,'r','LineWidth',2);
                hold off
        
             end;
        %-----------Display XY displacment and CM of all the roi on different figures------------------
    
        %-----------------Display Total displacment of all the roi=f(frame) on different figures--------------------- 
            if app.DisplayTotalDisplacmentofEveryCellCheckBox.Value==1 
    
                for Roi=1:app.NroiManualSelect
                    app.Nfig=app.Nfig+1; figure(app.Nfig);    
                    plot(app.DispT(:,Roi));
                    title([app.NameMovie, '  ','Tot displ cell ',num2str(Roi),' Frames ',num2str(app.FirstFrame), ' - ',num2str(app.LastFrame)]);
                    xlabel('Frames'); ylabel('Total displacement [pixels]')
                    grid on
                end;
            end;
    %-----------------Display Total displacment of all the roi=f(frame) on different figures---------------------
    
     
               %-----------------Display Total displacment of all the roi=f(frame) on the same figure---------------------
             app.Nfig=app.Nfig+1; figure(app.Nfig); 
             hold on
             for Roi=1:app.NroiManualSelect
                  plot(app.DispT(app.FirstFrame:app.LastFrame,Roi)); %       ou (:,Roi)                           <--------------------------------
                  title([app.NameMovie, '  ','Tot displ of all cells ',' Frames ',num2str(app.FirstFrame), ' - ',num2str(app.LastFrame)]);
                  xlabel('Frames'); ylabel('Total displacement [pixels]')
                  grid on
             end;
             hold off
              %-----------------Display Total displacment of all the roi=f(frame) on the same figure---------------------


    %---------------Calculate and display Total displacment of all the ROIs on a single figures---------------------
             for Roi=1:app.NroiManualSelect
                TotalDispl(Roi)=sum(app.DispT(:,Roi)); %total displacment (Eucledian) of the ROI
                TotalDisplPfr(Roi)=TotalDispl(Roi)/(app.LastFrame-app.FirstFrame); %total displacment per frame (Eucledian) of the ROI 
             end;
       
             app.TotDisplPfr=TotalDisplPfr;
             
             TotalDisplAllCells=sum(TotalDispl) %Calculates the total displacment of ALL the cells
             TotalDisplAllCellsPfr=sum(TotalDisplPfr) %Calculates the total displacment of ALL the cells per frame
     
             AverDispAllCells=TotalDisplAllCells/app.NroiManualSelect; %Calculates the Average displ of ALL the cells 
             AverDispAllCellsPfr=TotalDisplAllCellsPfr/app.NroiManualSelect; %Calculates the Average displ of ALL the cells
     
             StdTotDispl=std(TotalDispl); %Standard deviation of total displ.
             StdTotDisplPfr=std(TotalDisplPfr); %Standard deviation of total displ.per frame
     
             app.Nfig=app.Nfig+1; figure(app.Nfig);
             bar(TotalDisplPfr);
             title([app.NameMovie, '  ','Tot displ of all the cells ',' Frames ',num2str(app.FirstFrame), ' - ',num2str(app.LastFrame)]);
         
             Str1='Cells, Avr disp all cells = ';
             Str2=num2str(AverDispAllCellsPfr);
             Str3=strcat(Str1,Str2); 
             Str4=', STDev = '
             Str5=strcat(Str3,Str4);
             Str6=num2str(StdTotDisplPfr);
             Str7=strcat(Str5,Str6);
             xlabel(Str7); ylabel('Total displacement [pixels]')
    %-----------------Display Total displacment of all the ROIs on a single figures---------------------    


     %-------------Display difference in brightness of the roi for each roi=f(frame) on different figures------------
    %             the brightness is normalized to the size of the roi
     %DispBrightEveryCell=get(handles.e_DispBrightEveryCell,'Value');
     
      if app.DisplayBrighthnessofEveryCellCheckBox.Value==1  
        for Roi=1:app.NroiManualSelect
            app.Nfig=app.Nfig+1; figure(app.Nfig);  
            plot(app.DifBright(:,Roi));
            title([app.NameMovie, '  ','Brightness difference for cell ',num2str(Roi),' Frames ',num2str(app.FirstFrame), ' - ',num2str(app.LastFrame)]);
            xlabel('Frames'); ylabel('Brightness difference')
            grid on
        end;
        
        %Display the sum of brightness difference for every cell on one
        %figure
        for Roi=1:app.NroiManualSelect
            SumDifBright(Roi)=sum(app.DifBright(:,Roi));
        end;
        app.Nfig=app.Nfig+1; figure(app.Nfig); 
        bar(SumDifBright);
        title([app.NameMovie, '  ','Sum of brightness difference for all cells ',' Frames ',num2str(app.FirstFrame), ' - ',num2str(app.LastFrame)]);
        xlabel('Frames'); ylabel('Brightness difference')
        grid on 
        
        
        TotSumDifBright=sum(sum(app.DifBright)); %total sum of brightnes of all the cells (All ROIS together)
        AvrTotSumDifBright=TotSumDifBright/app.NroiManualSelect; %average of the prevoius
        
        %transfer all brightnes diffs in on vector
        n=1;
        for Roi=1:app.NroiManualSelect
            for Fr=app.FirstFrame:app.LastFrame
                AllBrighDifAllRoiAllFrames(n)=app.DifBright(Fr,Roi);
                n=n+1;
            end
        end
        StdDifBright=std(AllBrighDifAllRoiAllFrames); % std of all Frames and all Rois
        
        Str1='Cells, Avr DifBright all cells = ';
        Str2=num2str(AvrTotSumDifBright);
        Str3=strcat(Str1,Str2); 
        Str4=', STDev = '
        Str5=strcat(Str3,Str4);
        Str6=num2str(StdDifBright);
        Str7=strcat(Str5,Str6);
        xlabel(Str7); ylabel('Dif.Brightness');
        
     end;
    %-------------Display difference in brightness of the roi for each roi=f(frame) on different figures------------


    %-------Display the violin plot of the whole displacment for all the cells in one figure-----
    app.Nfig=app.Nfig+1; figure(app.Nfig); 
    [h,L,MX,MED]=f_violin(app.DispT);
    ylabel('Change','FontSize',14);
    xlabel('Cell nb');
    title([app.NameMovie, '  ','Spc displ of all cells ',' Frames ',num2str(app.FirstFrame), ' - ',num2str(app.LastFrame)]);
    
    YscViolPlot=app.YScaleViolinPlotEditField.Value;
    axis([0 app.NroiManualSelect+2 -YscViolPlot YscViolPlot]); %                                
    grid on
    %-------Display the violin plot of the whole displacment for all the cells in one figure-----


    %-------Displays the violin plot displ=f(t)for every ROI----------------
    %Reads from the GUI the number of violins to plot
    
     %DispDispViolinEveryCell=get(handles.e_DispDispViolinEveryCell,'Value');
     if app.DisplayViolinPlotforEveryCellCheckBox.Value==1 
     
        Nviolins=app.nViolinsEditField.Value;  
        NFrames=app.LastFrame-app.FirstFrame;   %Number of frames
            
        for Roi=1:app.NroiManualSelect             %numero de la cellule 
            n_fr_per_viol=round(NFrames/Nviolins); %longeur du vecteur
            clear vio;
            vio(1:n_fr_per_viol,1:Nviolins)=0; %vecteur qui contient les val pour les violons
            deb=1;
            nn=0;
            
            n_Fr=app.FirstFrame-1;%initialisation des frames
            vi=1;%initialisation des violons
            n=1;%initialisation des frames de chaque violon
            while n_Fr<app.LastFrame
                n_Fr=n_Fr+1;
                n=n+1;
                if n>n_fr_per_viol
                    n=1;
                    vi=vi+1; %violon suivant
                end
                vio(n,vi)=app.DispT(n_Fr,Roi);
            end;
    
            app.Nfig=app.Nfig+1; figure(app.Nfig); 
    
            [h,L,MX,MED]=f_violin(vio);
            ylabel('Change','FontSize',14);
            xlabel(['Frames : ',num2str(app.FirstFrame),' - ',num2str((app.FirstFrame+NFrames))]);
            title([app.NameMovie,' ','Spc disp of cell ',num2str(Roi),' Frames ',num2str(app.FirstFrame), ' - ',num2str(app.FirstFrame+NFrames)]);
            axis([0 Nviolins+2 -YscViolPlot YscViolPlot]);%  Ajuste la taille du graphique <----------------------------
            grid on
            %hold off 
        end;
     end;
     hold off
    %-------Displays the violin plot displ=f(t)for every ROI----------------


    %---------------Displays every Roi in a separate figure---------------
    
    DispRoiImages=app.DisplayAllROIsCheckBox.Value;
    if DispRoiImages==1
        Image=read(app.Movie, [app.FirstFrame]);%frame nb 1
        Image=rgb2gray(Image); %met l'image en nb
        MaxRoiImSize=50;
    
        %erifie si il faut aficher toutes les images des ROI
    
    
        for n=1:app.NroiManualSelect
            x1=app.posL_x(n);
            y1=app.posU_y(n);
            x2=app.posR_x(n);
            y2=app.posL_y(n);
            ImgRoi=imcrop(Image,[x1 y1 (x2-x1) (y2-y1)]); %stores ROI 
            app.Nfig=app.Nfig+1; figure(app.Nfig); 
            imshow(ImgRoi);
         end
    end;
    %---------------Displays every Roi in a separate figure---------------


               %---------------Displays displacement = f(Cell radius)------------------
    if app.DisplayDisplacementfCellRadiusCheckBox.Value==1 
        
        app.Nfig=app.Nfig+1; figure(app.Nfig); 
        imshow(app.ImgSurfCellDef);


        s = regionprops(app.ImgSurfCellDef,'centroid');
        centroids = cat(1, s.Centroid);
        hold on
        for n=1:length(centroids)
               %Writes ROI nb 
                numero=int2str(n);
                text(centroids(n,1),centroids(n,2),numero,'Color','red');
        end


        %Displays every ROI cell surface image
        for n=1:app.NroiManualSelect
            x1=app.posL_x(n);
            y1=app.posU_y(n);
            x2=app.posR_x(n);
            y2=app.posL_y(n);
            
            ImgRoi=imcrop(app.ImgSurfCellDef,[x1 y1 (x2-x1) (y2-y1)]); %stores ROI 
            
            if DispRoiImages==1
                app.Nfig=app.Nfig+1; figure(app.Nfig); 
                imshow(ImgRoi);
            end;
            CellSurfROI(n)=sum(sum(ImgRoi));%calculates ROI surf
            %CellSurfROI(n)=CellSurfROI(n)/256; %Divides the obtained nb by 256 to get the nb of pixels 
        end;     
       
        app.Nfig=app.Nfig+1; figure(app.Nfig); 
        bar(CellSurfROI)%displays ROI surf for all ROIs
        grid on
        title([app.NameMovie,' ','Surface of ',num2str(app.NroiManualSelect),'ROIs',' Frame ',num2str(app.FirstFrame)]);


        %Displays displacment = f(cell radius) 
        app.Nfig=app.Nfig+1; figure(app.Nfig);
        plot((sqrt(CellSurfROI)/3.14),TotalDisplPfr,'+');
        hold on
        grid on
        title([app.NameMovie,' ','Tot displ = f(cell radius) nRoi = ',num2str(app.NroiManualSelect),' Frame ',num2str(app.FirstFrame), ' - ',num2str(app.LastFrame)]);
          
        p=polyfit((sqrt(CellSurfROI)/3.14),TotalDisplPfr,1)%polynome de premier ordre
        coefPoly1=p(1)
        coefPoly2=p(2)
        x1=linspace(min(sqrt(CellSurfROI)/3.14),max(sqrt(CellSurfROI)/3.14));
        y1=polyval(p,x1);
        plot(x1,y1,'--','LineWidth',2);
        ylabel('Displacment / Frame')
        xlabel(['Cell radius [pixels] Polyfit coef :',num2str(coefPoly1),' and ',num2str(coefPoly2)])  
        hold off       
    end;
     
     %---------------Displays displacement = f(Cell radius)------------------
    
    
        end

        % Value changed function: 
        % DisplayXYDisplacmentofEveryCellCheckBox
        function DisplayXYDisplacmentofEveryCellCheckBoxValueChanged(app, event)
            value = app.DisplayXYDisplacmentofEveryCellCheckBox.Value;
            
        end

        % Button pushed function: ExportXYDisplacementDataButton
        function ExportXYDisplacementDataButtonPushed(app, event)
               %---------------Writes the displacment data in an EXCEL file-----------------------      
        FileName=fullfile(app.PathMovie,'Displacement_data.txt')
        fid=fopen(FileName,'wt'); %ouverture du fichier 
            
        ch=[app.NameMovie,'\n'];%ecriture nom film \n= retour chariot
        fprintf(fid,ch);
            
        %ecriture legende
        ch=['roi','\t','img','\t','x','\t','y','\t','\n'];
        fprintf(fid,ch);
            
        for Roi=1:app.NroiManualSelect
            for Img=app.FirstFrame:app.LastFrame
            %deplacement en x et y d'un frame a l autre
                Y=app.DispY(Img,Roi);   
                X=app.DispX(Img,Roi);
                %a=disp_Ang(Img,Roi);  %deplacement angulaire
           
                %ecriture donnees
                ch=[num2str(Roi),'\t',num2str(Img),'\t',num2str(X),'\t',num2str(Y),'\t','\n']; 
                fprintf(fid,ch);
            end;
        end;
        fclose(fid);    %fermeture du fichier tout a la fin
              %   end;
    
               %---------------Writes the displacment data in an EXCEL file-----------------------
        end

        % Button pushed function: ExportBrightnessDataButton
        function ExportBrightnessDataButtonPushed(app, event)
         disp('Save Brightness file')
       
        FileName=fullfile(app.PathMovie,'Brightness_data.txt')
        fid=fopen(FileName,'wt'); %ouverture du fichier 
            
        ch=[app.NameMovie,'\n'];%ecriture nom film \n= retour chariot
        fprintf(fid,ch);
            
        %ecriture legende
        ch=['roi','\t','img','\t','Brightness','\n'];
        fprintf(fid,ch);
            
        for Roi=1:app.NroiManualSelect
            for Img=app.FirstFrame:app.LastFrame   
                Dbrig=app.DifBright(Img,Roi);
                %ecriture donnees
                ch=[num2str(Roi),'\t',num2str(Img),'\t',num2str(Dbrig),'\n']; 
                fprintf(fid,ch);
                disp('Roi')
                disp(Roi);
            end;
        end;
        fclose(fid);    %fermeture du fichier tout a la fin
        disp('Close Brightness file')
        end

        % Button pushed function: 
        % ExportTotalDisplacementofAllROIsButton
        function ExportTotalDisplacementofAllROIsButtonPushed(app, event)
        FileName=fullfile(app.PathMovie,'AllROIdisplSum_data.txt')
        fid=fopen(FileName,'wt'); %ouverture du fichier 
            
        ch=[app.NameMovie,'\n'];%ecriture nom film \n= retour chariot
        fprintf(fid,ch);
            
        %ecriture legende
        ch=['roi','\t','TotDispPfr','\n'];
        fprintf(fid,ch);
        
        
        for Roi=1:app.NroiManualSelect
            X=app.TotDisplPfr(Roi)
            ch=[num2str(Roi),'\t',num2str(X),'\n']; 
            fprintf(fid,ch);
        end;
        fclose(fid);    %fermeture du fichier tout a la fin
            
        end

        % Button pushed function: CLEARALLVARIABLESButton
        function CLEARALLVARIABLESButtonPushed(app, event)
            clear all
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.PathMovieEditFieldLabel = uilabel(app.FileTab);
            app.PathMovieEditFieldLabel.Text = 'Path Movie';
            app.PathMovieEditField = uieditfield(app.FileTab, 'text');
            app.PathMovieEditField.Value = 'D:\Datas-Sandor-Kasas-3TB\Projets\YeastOnMobile\ONMD_soft Development\ONMD_soft_App_1a';
            app.LoadMovieButton = uibutton(app.FileTab, 'push');
            app.LoadMovieButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMovieButtonPushed, true);
            app.LoadMovieButton.Text = 'Load Movie';
            app.NameMovieEditFieldLabel = uilabel(app.FileTab);
            app.NameMovieEditFieldLabel.Text = 'Name Movie';
            app.NameMovieEditField = uieditfield(app.FileTab, 'text');
            app.NameMovieEditField.Value = '\FilmInes_2.avi';
            app.FirstFrameEditFieldLabel = uilabel(app.FileTab);
            app.FirstFrameEditFieldLabel.Text = 'First Frame';
            app.FirstFrameEditField = uieditfield(app.FileTab, 'text');
            app.FirstFrameEditField.Value = '2';
            app.LastFrameEditFieldLabel = uilabel(app.FileTab);
            app.LastFrameEditFieldLabel.Text = 'Last Frame';
            app.LastFrameEditField = uieditfield(app.FileTab, 'text');
            app.LastFrameEditField.Value = '50';
            app.DeltaFramesEditFieldLabel = uilabel(app.FileTab);
            app.DeltaFramesEditFieldLabel.Text = 'Delta Frames';
            app.DeltaFramesEditField = uieditfield(app.FileTab, 'text');
            app.DeltaFramesEditField.Value = '5';
            app.DisplayFirstFrameButton = uibutton(app.FileTab, 'push');
            app.DisplayFirstFrameButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayFirstFrameButtonPushed, true);
            app.DisplayFirstFrameButton.Text = 'Display First Frame';
            app.DisplayLastFrameButton = uibutton(app.FileTab, 'push');
            app.DisplayLastFrameButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayLastFrameButtonPushed, true);
            app.DisplayLastFrameButton.Text = 'Display Last Frame';
            app.MovieQualityTestTab = uitab(app.TabGroup);
            app.MovieQualityTestTab.Title = 'Movie Quality Test';
            app.DisplayChangingPixelsButton = uibutton(app.MovieQualityTestTab, 'push');
            app.DisplayChangingPixelsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayChangingPixelsButtonPushed, true);
            app.DisplayChangingPixelsButton.Text = 'Display Changing Pixels';
            app.DisplayPixelsIntensityVariationsButton = uibutton(app.MovieQualityTestTab, 'push');
            app.DisplayPixelsIntensityVariationsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayPixelsIntensityVariationsButtonPushed, true);
            app.DisplayPixelsIntensityVariationsButton.Text = 'Display Pixels Intensity Variations';
            app.ChangingPixelsImageContrastEditFieldLabel = uilabel(app.MovieQualityTestTab);
            app.ChangingPixelsImageContrastEditFieldLabel.Text = 'Changing Pixels Image Contrast ';
            app.ChangingPixelsImageContrastEditField = uieditfield(app.MovieQualityTestTab, 'numeric');
            app.ChangingPixelsImageContrastEditField.Value = 0.9;
            app.CellRecognitionTab = uitab(app.TabGroup);
            app.CellRecognitionTab.Title = 'Cell Recognition';
            app.MorphologicalFilteringButton = uibutton(app.CellRecognitionTab, 'push');
            app.MorphologicalFilteringButton.ButtonPushedFcn = createCallbackFcn(app, @MorphologicalFilteringButtonPushed, true);
            app.MorphologicalFilteringButton.Text = 'Morphological Filtering';
            app.DilatDiskSizeEditFieldLabel = uilabel(app.CellRecognitionTab);
            app.DilatDiskSizeEditFieldLabel.Text = 'Dilat Disk Size';
            app.DilatDiskSizeEditField = uieditfield(app.CellRecognitionTab, 'text');
            app.DilatDiskSizeEditField.Value = '6';
            app.CloseDiskSizeEditFieldLabel = uilabel(app.CellRecognitionTab);
            app.CloseDiskSizeEditFieldLabel.Text = 'Close Disk Size';
            app.CloseDiskSizeEditField = uieditfield(app.CellRecognitionTab, 'text');
            app.CloseDiskSizeEditField.Value = '2';
            app.ManualSelectionPanel = uipanel(app.CellRecognitionTab);
            app.ManualSelectionPanel.Title = 'Manual Selection';
            app.nROIManSelectEditFieldLabel = uilabel(app.ManualSelectionPanel);
            app.nROIManSelectEditFieldLabel.Text = 'n ROI Man Select';
            app.nROIManSelectEditField = uieditfield(app.ManualSelectionPanel, 'numeric');
            app.ValidateManualSelectionButton = uibutton(app.ManualSelectionPanel, 'push');
            app.ValidateManualSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateManualSelectionButtonPushed, true);
            app.ValidateManualSelectionButton.Text = 'Validate Manual Selection';
            app.AutomaticSelectionPanel = uipanel(app.CellRecognitionTab);
            app.AutomaticSelectionPanel.Title = 'Automatic Selection';
            app.RemoveROIsButton = uibutton(app.AutomaticSelectionPanel, 'push');
            app.RemoveROIsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveROIsButtonPushed, true);
            app.RemoveROIsButton.Text = 'Remove ROIs';
            app.RightClicktostopLabel = uilabel(app.AutomaticSelectionPanel);
            app.RightClicktostopLabel.Text = 'Right Click to stop';
            app.ValidateAutomaticSelectionButton = uibutton(app.AutomaticSelectionPanel, 'push');
            app.ValidateAutomaticSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateAutomaticSelectionButtonPushed, true);
            app.ValidateAutomaticSelectionButton.Text = 'Validate Automatic Selection';
            app.AutomaticROIboxsizeEditFieldLabel = uilabel(app.CellRecognitionTab);
            app.AutomaticROIboxsizeEditFieldLabel.Text = 'Automatic ROI box size';
            app.AutomaticROIboxsizeEditField = uieditfield(app.CellRecognitionTab, 'numeric');
            app.AutomaticROIboxsizeEditField.Value = 10;
            app.BasicImageProcessingTab = uitab(app.TabGroup);
            app.BasicImageProcessingTab.Title = 'Basic Image Processing';
            app.CorrectNonUniformIlluminationButton = uibutton(app.BasicImageProcessingTab, 'push');
            app.CorrectNonUniformIlluminationButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectNonUniformIlluminationButtonPushed, true);
            app.CorrectNonUniformIlluminationButton.Text = 'Correct Non Uniform Illumination';
            app.LoadFlatFieldMovieButton = uibutton(app.BasicImageProcessingTab, 'push');
            app.LoadFlatFieldMovieButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFlatFieldMovieButtonPushed, true);
            app.LoadFlatFieldMovieButton.Text = 'Load Flat Field Movie';
            app.FlatFieldMoviePathEditFieldLabel = uilabel(app.BasicImageProcessingTab);
            app.FlatFieldMoviePathEditFieldLabel.Text = 'Flat Field Movie Path';
            app.FlatFieldMoviePathEditField = uieditfield(app.BasicImageProcessingTab, 'text');
            app.FlatFieldMoviePathEditField.Value = 'D:\Datas-Sandor-Kasas-3TB\Projets\YeastOnMobile\ONMD_soft Development\ONMD_soft_App_1a\';
            app.FlatFieldMovieNameEditFieldLabel = uilabel(app.BasicImageProcessingTab);
            app.FlatFieldMovieNameEditFieldLabel.Text = 'Flat Field Movie Name';
            app.FlatFieldMovieNameEditField = uieditfield(app.BasicImageProcessingTab, 'text');
            app.FlatFieldMovieNameEditField.Value = 'movi blank bwr.avi';
            app.DivideallFrameswithFFImageCheckBox = uicheckbox(app.BasicImageProcessingTab);
            app.DivideallFrameswithFFImageCheckBox.Text = 'Divide all Frames with FF Image';
            app.DisplayResultsTab = uitab(app.TabGroup);
            app.DisplayResultsTab.Title = 'Display Results';
            app.DisplayResultsButton = uibutton(app.DisplayResultsTab, 'push');
            app.DisplayResultsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayResultsButtonPushed, true);
            app.DisplayResultsButton.Text = 'Display Results';
            app.DisplayXYDisplacmentofEveryCellCheckBox = uicheckbox(app.DisplayResultsTab);
            app.DisplayXYDisplacmentofEveryCellCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayXYDisplacmentofEveryCellCheckBoxValueChanged, true);
            app.DisplayXYDisplacmentofEveryCellCheckBox.Text = 'Display XY Displacment of Every Cell';
            app.XYScaleofDisplacmentPlotEditFieldLabel = uilabel(app.DisplayResultsTab);
            app.XYScaleofDisplacmentPlotEditFieldLabel.Text = 'XY Scale of Displacment Plot';
            app.XYScaleofDisplacmentPlotEditField = uieditfield(app.DisplayResultsTab, 'numeric');
            app.XYScaleofDisplacmentPlotEditField.Value = 2;
            app.DisplayTotalDisplacmentofEveryCellCheckBox = uicheckbox(app.DisplayResultsTab);
            app.DisplayTotalDisplacmentofEveryCellCheckBox.Text = 'Display Total Displacment of Every Cell';
            app.DisplayBrighthnessofEveryCellCheckBox = uicheckbox(app.DisplayResultsTab);
            app.DisplayBrighthnessofEveryCellCheckBox.Text = 'Display Brighthness of Every Cell';
            app.YScaleViolinPlotEditFieldLabel = uilabel(app.DisplayResultsTab);
            app.YScaleViolinPlotEditFieldLabel.Text = 'Y Scale Violin Plot';
            app.YScaleViolinPlotEditField = uieditfield(app.DisplayResultsTab, 'numeric');
            app.YScaleViolinPlotEditField.Value = 5;
            app.DisplayViolinPlotforEveryCellCheckBox = uicheckbox(app.DisplayResultsTab);
            app.DisplayViolinPlotforEveryCellCheckBox.Text = 'Display Violin Plot for Every Cell';
            app.nViolinsEditFieldLabel = uilabel(app.DisplayResultsTab);
            app.nViolinsEditFieldLabel.Text = 'n Violins';
            app.nViolinsEditField = uieditfield(app.DisplayResultsTab, 'numeric');
            app.nViolinsEditField.Value = 5;
            app.DisplayAllROIsCheckBox = uicheckbox(app.DisplayResultsTab);
            app.DisplayAllROIsCheckBox.Text = 'Display All ROIs';
            app.DisplayDisplacementfCellRadiusCheckBox = uicheckbox(app.DisplayResultsTab);
            app.DisplayDisplacementfCellRadiusCheckBox.Text = 'Display Displacement f Cell Radius ';
            app.ExporttoExcelTab = uitab(app.TabGroup);
            app.ExporttoExcelTab.Title = 'Export to Excel';
            app.ExportXYDisplacementDataButton = uibutton(app.ExporttoExcelTab, 'push');
            app.ExportXYDisplacementDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportXYDisplacementDataButtonPushed, true);
            app.ExportXYDisplacementDataButton.Text = 'Export X Y Displacement Data';
            app.ExportBrightnessDataButton = uibutton(app.ExporttoExcelTab, 'push');
            app.ExportBrightnessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportBrightnessDataButtonPushed, true);
            app.ExportBrightnessDataButton.Text = 'Export Brightness Data';
            app.ExportTotalDisplacementofAllROIsButton = uibutton(app.ExporttoExcelTab, 'push');
            app.ExportTotalDisplacementofAllROIsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTotalDisplacementofAllROIsButtonPushed, true);
            app.ExportTotalDisplacementofAllROIsButton.Text = 'Export Total Displacement of All ROIs';
            app.ONMD_soft_App_2Label = uilabel(app.UIFigure);
            app.ONMD_soft_App_2Label.Text = 'ONMD_soft_App_2';
            app.CloseallwindowsButton = uibutton(app.UIFigure, 'push');
            app.CloseallwindowsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallwindowsButtonPushed, true);
            app.CloseallwindowsButton.Text = 'Close all windows';
            app.CLEARALLVARIABLESButton = uibutton(app.UIFigure, 'push');
            app.CLEARALLVARIABLESButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARALLVARIABLESButtonPushed, true);
            app.CLEARALLVARIABLESButton.Text = 'CLEAR ALL VARIABLES';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ONMD_soft_App_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end